classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % UI Figure
        UIAxes                  matlab.ui.control.UIAxes           % Title
        LabelListBox            matlab.ui.control.Label            % Disturb
        ListBox                 matlab.ui.control.ListBox          % Normal...
        AlphaSliderLabel        matlab.ui.control.Label            % alpha
        AlphaSlider             matlab.ui.control.Slider           % [0 1]
        BetaSliderLabel         matlab.ui.control.Label            % beta
        BetaSlider              matlab.ui.control.Slider           % [0 100]
        PlotSignalButton        matlab.ui.control.Button           % Plot
        Alpha3SliderLabel       matlab.ui.control.Label            % alpha3
        Alpha3Slider            matlab.ui.control.Slider           % [0 100]
        Alpha5SliderLabel       matlab.ui.control.Label            % alpha5
        Alpha5Slider            matlab.ui.control.Slider           % [0 100]
        Alpha7SliderLabel       matlab.ui.control.Label            % alpha7
        Alpha7Slider            matlab.ui.control.Slider           % [0 100]
        t1NumericEditLabel      matlab.ui.control.Label            % t1
        t1NumericEdit           matlab.ui.control.NumericEditField % [-Inf ...
        t2NumericEditLabel      matlab.ui.control.Label            % t2
        t2NumericEdit           matlab.ui.control.NumericEditField % [-Inf ...
        Export                  matlab.ui.control.Button           % Export 
        LabelNumericEditField   matlab.ui.control.Label            % # of C...
        NumOfCycles             matlab.ui.control.NumericEditField % [-Inf ...
        NumOfCyclesLabel        matlab.ui.control.Label            % Number...
        Nexport                 matlab.ui.control.NumericEditField % [-Inf ...
        Button                  matlab.ui.control.Button           % Export...
        Label                   matlab.ui.control.Label            % Total ...
        Ntotalexport            matlab.ui.control.NumericEditField % [-Inf ...
        ButtonGroup             matlab.ui.container.ButtonGroup    % Frequency
        f50                     matlab.ui.control.RadioButton      % 50 Hz
        f60                     matlab.ui.control.RadioButton      % 60 Hz
        LabelTextArea           matlab.ui.control.Label           
        TimeDiff                matlab.ui.control.TextArea         % The ch...
        TauSliderLabel          matlab.ui.control.Label            % tau
        TauSlider               matlab.ui.control.Slider           % [8 40]
        FnSliderLabel           matlab.ui.control.Label            % fn
        FnSlider                matlab.ui.control.Slider           % [300 900]
        KSliderLabel            matlab.ui.control.Label            % K
        KSlider                 matlab.ui.control.Slider           % [1.000...
        AlphaFlickerSliderLabel matlab.ui.control.Label            % alpha
        AlphaFlickerSlider      matlab.ui.control.Slider           % [1.000...
        NoiseSliderLabel        matlab.ui.control.Label            % SNR
        NoiseSlider             matlab.ui.control.Slider           % [0 20]
        Noise                   matlab.ui.container.ButtonGroup    % Noise
        NoNoise                 matlab.ui.control.RadioButton      % No noise
        WGN                     matlab.ui.control.RadioButton      % WGN
        ButtonGroup2            matlab.ui.container.ButtonGroup    % Noise
        RadioButton             matlab.ui.control.RadioButton      % No noise
        RadioButton2            matlab.ui.control.RadioButton      % WGN
    end

    
    properties (Access = public)
        
        % Time Parametes:
        t_start = 0; % TODO : Chossen by User/Random
        t_end ; % TODO : Chossen by User/Random
        dt = 0.0001; % TODO : ASK RAM Chossen by User/Random
        t;
        
        f = 50; % [Hz] % TODO : Chossen by User/Random
        Tcycle;% =1./(f); % % TODO : Chossen by User/Random
        NumofTcycles = 10;
        
        %
        %signal_label; % type
        % Signal Parameters:
        alpha = 0;
        alpha_flicker = 0;
        beta = 0;
        alpha3 = 0;
        alpha5 = 0;
        alpha7 = 0;
        alpha1; %TODO : private?
        % Transiet :
        tau ;
        Fn ;
        k;
        
        
        % Most disturbs  :
        t1 = 0;
        t2 = 0;
        
        % The current signal :
        signal;%y ;
        noisy_signal;
        signal_label = 'Normal';
        
        
        % Exporting signals :
        %label = '';
        %signal = [];% =y
        %signal_label4DB = struct(label,signal);
        field1 = 'labels';
        field2 = 'signals';
        DataBase; %Defined in start-up callback
        
        N=0; % Number of vectors to export
        
    end

    properties (Access = private)
        DBDialogBox % Description
    end


    methods (Access = public)
        
        function dataBaseFunc(app)
            app.signal_label = 'tryy';
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name ='Power Quality Signals GUI';
            
            %close ALL FORCE;% manage to close but also the wanted istance
            close all;
            %%%%%% TODO:  close old instances?;
            clc;
            
            defaultOffVisibality(app);
            
            app.DataBase = struct(app.field1,[],app.field2,[]);% = {};
            
            
            app.Tcycle =1./(app.f); % [sec]
            app.t_end = app.NumofTcycles * app.Tcycle;
            app.t = [app.t_start : app.dt : app.t_end];
            
            % axis([0 10*pi -1.5 1.5]);% TODO : let the user set boundaries?
            
            app.signal = sin(2*pi*app.f*app.t); %Deafultic signal
            app.noisy_signal = awgn(app.signal,0); %Deafultic signal
            PlotSignal(app);
        end

        % ListBox value changed function
        function DisListBox(app)
            
            app.signal_label = app.ListBox.Value;
            
            chooseModel(app);
            app.UIAxes.Title.String = app.signal_label;
            
            %if app.signal_label=='Normal';TODO - plot
            [app.signal,app.noisy_signal] = specifyModel(app,0);
            
            PlotSignal(app);
        end

        % Switch value changed function
        function StartPlotting(app)
            %
            %             PlotOrNot = app.Switch.Value ;
            %             if strcmp(PlotOrNot,'On')
            %                 type = app.ListBox.Value;
            %                 [t,y,y_title] = choose_model_power_quality_signal_labelances(type);
            %             end
            
            
        end

        % AlphaSlider value changed function
        function AlphaSliderValueChanged(app)
            
            app.alpha = app.AlphaSlider.Value;
            
            [app.signal,app.noisy_signal] = specifyModel(app,0);
            
            if t1_t2_diff_legal(app.t1,app.t2,app.Tcycle,app.signal_label)
                PlotSignal(app);
            end
        end

        % BetaSlider value changed function
        function BetaSliderValueChanged(app)
            
            app.beta = app.BetaSlider.Value;
            
            [app.signal,app.noisy_signal] = specifyModel(app,0);
            
            if t1_t2_diff_legal(app.t1,app.t2,app.Tcycle,app.signal_label)
                PlotSignal(app);
            end
        end

        % PlotSignalButton button pushed function
        function PlotSignal(app)
            if strcmp(app.Noise.SelectedObject.Text,'WGN')
                plot(app.UIAxes,app.t*1E+3,app.noisy_signal); %miliseconds
            else
                plot(app.UIAxes,app.t*1E+3,app.signal); %miliseconds
                %plot(app.UIAxes,app.t,app.signal); %seconds
            end
            %axis([0 10*pi -1.5 1.5]);% TODO : let the user set boundaries?
            app.UIAxes.Title.String = app.signal_label;
            
            %TODO : zoom-in option ...
        end

        % Alpha3Slider value changed function
        function Alpha3SliderValueChanged(app)
            app.alpha3 = app.Alpha3Slider.Value;
            
            [app.signal,app.noisy_signal] = specifyModel(app,0);
            
            if t1_t2_diff_legal(app.t1,app.t2,app.Tcycle,app.signal_label)
                PlotSignal(app);
            end
        end

        % Alpha5Slider value changed function
        function Alpha5SliderValueChanged(app)
            app.alpha5 = app.Alpha5Slider.Value;
            
            [app.signal,app.noisy_signal] = specifyModel(app,0);
            
            if t1_t2_diff_legal(app.t1,app.t2,app.Tcycle,app.signal_label)
                PlotSignal(app);
            end
        end

        % Alpha7Slider value changed function
        function Alpha7SliderValueChanged(app)
            app.alpha7 = app.Alpha7Slider.Value;
            
            [app.signal,app.noisy_signal] = specifyModel(app,0);
            
            if t1_t2_diff_legal(app.t1,app.t2,app.Tcycle,app.signal_label)
                PlotSignal(app);
            end
            
        end

        % Export button pushed function
        function ExportCallBack(app)
            %% Disable GUI button options button while dialog is open
            %app.OptionsButton.Enable = 'off';
          app.DBDialogBox = DataBaseCreator();
          
         %  DataBaseCreator
         %  func(app,1) ;
            %export(app)
            specifyModel(app,1);
            temp= app.DataBase
            save('tryFile.mat','temp'); %Save for each addition
            
        end

        % Nexport value changed function
        function NexportValueChanged(app)
            app.N = app.Nexport.Value;
            
        end

        % Button button pushed function
        function ExportAllButtonPushed(app)
            
            for i=1:length(app.ListBox.Items)
                app.signal_label = app.ListBox.Items{i};
                for j=1:app.N
                    chooseModel(app);
                    specifyModel(app,1);%random flag =1
                end
            end
            defaultOffVisibality(app);
            temp= app.DataBase
            save('tryFile.mat','temp'); %Save for each addition
        end

        % Ntotalexport value changed function
        function NtotalexportValueChanged(app)
            app.N = app.Ntotalexport.Value;
            
        end

        % ButtonGroup selection change function
        function FreqSelectionChanged(app, event)
            switch app.ButtonGroup.SelectedObject
                case app.f50
                    app.f = 50;
                case app.f60
                    app.f = 60;
            end
            
            app.Tcycle =1./(app.f); % [sec]
            
            [app.signal,app.noisy_signal] = specifyModel(app,0);
            
            if t1_t2_diff_legal(app.t1,app.t2,app.Tcycle,app.signal_label)
                PlotSignal(app);
            end
        end

        % NumOfCycles value changed function
        function NumOfCyclesValueChanged(app)
            app.NumofTcycles = app.NumOfCycles.Value;
            
            app.t_end = app.NumofTcycles*app.Tcycle;
            
            app.t = [app.t_start : app.dt : app.t_end];
            app.signal=specifyModel(app,0);
            PlotSignal(app);
            
            
        end

        % t1NumericEdit value changed function
        function t1NumericEditValueChanged(app)
            %             value = app.t1NumericEdit.Value;
            %             app.t1 = value./1000 ;% ms to sec
            
            app.t1 = app.t1NumericEdit.Value;
            
            [app.signal] = specifyModel(app,0);
            
            if t1_t2_diff_legal(app.t1,app.t2,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                %pop-up msg
            end
        end

        % t2NumericEdit value changed function
        function t2NumericEditValueChanged(app)
            %             value = app.t2NumericEdit.Value;
            %             app.t2 = value./1000 ;% ms to sec
            
            app.t2 = app.t2NumericEdit.Value;
            
            [app.signal,app.noisy_signal] = specifyModel(app,0);
            
            if t1_t2_diff_legal(app.t1,app.t2,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                %pop-up msg
            end
        end

        % TauSlider value changed function
        function TauSliderValueChanged(app)
            value = app.TauSlider.Value;
            app.tau = value./1000 ;% ms to sec
            
            [app.signal,app.noisy_signal] = specifyModel(app,0);
            
            if t1_t2_diff_legal(app.t1,app.t2,app.Tcycle,app.signal_label)
                PlotSignal(app);
            end
            
        end

        % FnSlider value changed function
        function FnSliderValueChanged(app)
            app.Fn = app.FnSlider.Value;
            
            [app.signal,app.noisy_signal] = specifyModel(app,0);
            
            if t1_t2_diff_legal(app.t1,app.t2,app.Tcycle,app.signal_label)
                PlotSignal(app);
            end        
        end

        % KSlider value changed function
        function KSliderValueChanged(app)
            app.k = app.KSlider.Value;
            
            [app.signal,app.noisy_signal] = specifyModel(app,0);
            
            if t1_t2_diff_legal(app.t1,app.t2,app.Tcycle,app.signal_label)
                PlotSignal(app);
            end            
        end

        % AlphaFlickerSlider value changed function
        function AlphaFlickerSliderValueChanged(app)
            
            app.alpha_flicker = app.AlphaFlickerSlider.Value;
            
            [app.signal,app.noisy_signal] = specifyModel(app,0);
            
            if t1_t2_diff_legal(app.t1,app.t2,app.Tcycle,app.signal_label)
                PlotSignal(app);
            end            
        end

        % NoiseSlider value changed function
        function NoiseSliderValueChanged(app)
            SNRinDb = app.NoiseSlider.Value;
            
            app.noisy_signal = awgn(app.signal,SNRinDb);
            
            PlotSignal(app);
            
        end

        % Noise selection change function
        function NoiseSelectionChanged(app, event)
            if strcmp(app.Noise.SelectedObject.Text,'WGN')
                app.NoiseSlider.Enable = 'on';
                app.NoiseSliderLabel.Enable = 'on';
                PlotSignal(app);
            else
                app.NoiseSlider.Enable = 'off';
                app.NoiseSliderLabel.Enable = 'off';
                PlotSignal(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 't [ms]');
            ylabel(app.UIAxes, 'V(t) [pu]');
            app.LabelListBox = uilabel(app.UIFigure);
            app.LabelListBox.Text = 'Disturb';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Normal', 'Sag', 'Swell', 'Interruption', 'Harmonics', 'Oscillatory transient', 'Sag+Harmonics', 'Swell+Harmonics', 'Flicker', 'Notch', 'Spike', 'Flicker+Harmonics', 'Interruption+Harmonics', 'Flicker+Sag', 'Flicker+Swell', 'Impulsive Transient'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @DisListBox);
            app.ListBox.Value = 'Normal';
            app.AlphaSliderLabel = uilabel(app.UIFigure);
            app.AlphaSliderLabel.Text = 'alpha';
            app.AlphaSlider = uislider(app.UIFigure);
            app.AlphaSlider.Limits = [0 1];
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaSliderValueChanged);
            app.BetaSliderLabel = uilabel(app.UIFigure);
            app.BetaSliderLabel.Text = 'beta';
            app.BetaSlider = uislider(app.UIFigure);
            app.BetaSlider.ValueChangedFcn = createCallbackFcn(app, @BetaSliderValueChanged);
            app.PlotSignalButton = uibutton(app.UIFigure, 'push');
            app.PlotSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSignal);
            app.PlotSignalButton.Enable = 'off';
            app.PlotSignalButton.Text = 'Plot';
            app.Alpha3SliderLabel = uilabel(app.UIFigure);
            app.Alpha3SliderLabel.Text = 'alpha3';
            app.Alpha3Slider = uislider(app.UIFigure);
            app.Alpha3Slider.ValueChangedFcn = createCallbackFcn(app, @Alpha3SliderValueChanged);
            app.Alpha5SliderLabel = uilabel(app.UIFigure);
            app.Alpha5SliderLabel.Text = 'alpha5';
            app.Alpha5Slider = uislider(app.UIFigure);
            app.Alpha5Slider.ValueChangedFcn = createCallbackFcn(app, @Alpha5SliderValueChanged);
            app.Alpha7SliderLabel = uilabel(app.UIFigure);
            app.Alpha7SliderLabel.Text = 'alpha7';
            app.Alpha7Slider = uislider(app.UIFigure);
            app.Alpha7Slider.ValueChangedFcn = createCallbackFcn(app, @Alpha7SliderValueChanged);
            app.t1NumericEditLabel = uilabel(app.UIFigure);
            app.t1NumericEditLabel.Text = 't1';
            app.t1NumericEdit = uieditfield(app.UIFigure, 'numeric');
            app.t1NumericEdit.ValueChangedFcn = createCallbackFcn(app, @t1NumericEditValueChanged);
            app.t2NumericEditLabel = uilabel(app.UIFigure);
            app.t2NumericEditLabel.Text = 't2';
            app.t2NumericEdit = uieditfield(app.UIFigure, 'numeric');
            app.t2NumericEdit.ValueChangedFcn = createCallbackFcn(app, @t2NumericEditValueChanged);
            app.Export = uibutton(app.UIFigure, 'push');
            app.Export.ButtonPushedFcn = createCallbackFcn(app, @ExportCallBack);
            app.Export.Text = {'Export '; 'Current Disturb'; ' to DataBase '};
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = '# of Cycles';
            app.NumOfCycles = uieditfield(app.UIFigure, 'numeric');
            app.NumOfCycles.ValueChangedFcn = createCallbackFcn(app, @NumOfCyclesValueChanged);
            app.NumOfCycles.Value = 10;
            app.NumOfCyclesLabel = uilabel(app.UIFigure);
            app.NumOfCyclesLabel.Text = 'Number of vectors';
            app.Nexport = uieditfield(app.UIFigure, 'numeric');
            app.Nexport.ValueChangedFcn = createCallbackFcn(app, @NexportValueChanged);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ExportAllButtonPushed);
            app.Button.Text = {'Export  DataBase '; '(equal # of vecs '; 'per diturb)'; ''};
            app.Label = uilabel(app.UIFigure);
            app.Label.Enable = 'off';
            app.Label.Text = 'Total # of vectors';
            app.Ntotalexport = uieditfield(app.UIFigure, 'numeric');
            app.Ntotalexport.ValueChangedFcn = createCallbackFcn(app, @NtotalexportValueChanged);
            app.Ntotalexport.Enable = 'off';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FreqSelectionChanged, true);
            app.ButtonGroup.Title = 'Frequency';
            app.ButtonGroup.Units = 'pixels';
            app.f50 = uiradiobutton(app.ButtonGroup);
            app.f50.Text = '50 Hz';
            app.f50.Value = true;
            app.f60 = uiradiobutton(app.ButtonGroup);
            app.f60.Text = '60 Hz';
            app.LabelTextArea = uilabel(app.UIFigure);
            app.LabelTextArea.Text = '';
            app.TimeDiff = uitextarea(app.UIFigure);
            app.TimeDiff.Value = {'The chosen time difference is not legal '};
            app.TauSliderLabel = uilabel(app.UIFigure);
            app.TauSliderLabel.Text = 'tau';
            app.TauSlider = uislider(app.UIFigure);
            app.TauSlider.Limits = [8 40];
            app.TauSlider.ValueChangedFcn = createCallbackFcn(app, @TauSliderValueChanged);
            app.TauSlider.Value = 8;
            app.FnSliderLabel = uilabel(app.UIFigure);
            app.FnSliderLabel.Text = 'fn';
            app.FnSlider = uislider(app.UIFigure);
            app.FnSlider.Limits = [300 900];
            app.FnSlider.ValueChangedFcn = createCallbackFcn(app, @FnSliderValueChanged);
            app.FnSlider.Value = 300;
            app.KSliderLabel = uilabel(app.UIFigure);
            app.KSliderLabel.Text = 'K';
            app.KSlider = uislider(app.UIFigure);
            app.KSlider.Limits = [0.1 0.4];
            app.KSlider.ValueChangedFcn = createCallbackFcn(app, @KSliderValueChanged);
            app.KSlider.Value = 0.4;
            app.AlphaFlickerSliderLabel = uilabel(app.UIFigure);
            app.AlphaFlickerSliderLabel.Text = {'alpha'; ' flicker'};
            app.AlphaFlickerSlider = uislider(app.UIFigure);
            app.AlphaFlickerSlider.Limits = [0.1 0.2];
            app.AlphaFlickerSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaFlickerSliderValueChanged);
            app.AlphaFlickerSlider.Value = 0.1;
            app.NoiseSliderLabel = uilabel(app.UIFigure);
            app.NoiseSliderLabel.Enable = 'off';
            app.NoiseSliderLabel.Text = 'SNR';
            app.NoiseSlider = uislider(app.UIFigure);
            app.NoiseSlider.Limits = [0 20];
            app.NoiseSlider.MajorTickLabels = {'0', '4', '8', '12', '16', '20db'};
            app.NoiseSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseSliderValueChanged);
            app.NoiseSlider.MinorTicks = [];
            app.NoiseSlider.Enable = 'off';
            app.Noise = uibuttongroup(app.UIFigure);
            app.Noise.SelectionChangedFcn = createCallbackFcn(app, @NoiseSelectionChanged, true);
            app.Noise.Title = 'Noise';
            app.Noise.Units = 'pixels';
            app.NoNoise = uiradiobutton(app.Noise);
            app.NoNoise.Text = 'No noise';
            app.NoNoise.Value = true;
            app.WGN = uiradiobutton(app.Noise);
            app.WGN.Text = 'WGN';
            app.ButtonGroup2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup2.Title = 'Noise';
            app.ButtonGroup2.Units = 'pixels';
            app.RadioButton = uiradiobutton(app.ButtonGroup2);
            app.RadioButton.Text = 'No noise';
            app.RadioButton.Value = true;
            app.RadioButton2 = uiradiobutton(app.ButtonGroup2);
            app.RadioButton2.Text = 'WGN';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end